classdef Remote_lock < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PingButton               matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        GetADCValueButton        matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        PD_Zoom                  matlab.ui.control.Spinner
        PDZoomLabel              matlab.ui.control.Label
        PD_Y                     matlab.ui.control.Spinner
        PDYLabel                 matlab.ui.control.Label
        ErrorOffset              matlab.ui.control.Spinner
        ErrorOffsetSpinnerLabel  matlab.ui.control.Label
        IPEditField              matlab.ui.control.EditField
        IPEditFieldLabel         matlab.ui.control.Label
        Freq_down50              matlab.ui.control.Button
        Freq_up50                matlab.ui.control.Button
        FrequencyEditField       matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel  matlab.ui.control.Label
        Fas_Unlock               matlab.ui.control.Button
        Fast_Lock                matlab.ui.control.Button
        FASTLabel                matlab.ui.control.Label
        SLOWLabel                matlab.ui.control.Label
        Slow_Unlock              matlab.ui.control.Button
        Slow_Lock                matlab.ui.control.Button
        Freq_down10              matlab.ui.control.Button
        Freq_down1               matlab.ui.control.Button
        Freq_up10                matlab.ui.control.Button
        Freq_up1                 matlab.ui.control.Button
        Freq_zero                matlab.ui.control.Button
        FrequencySlider          matlab.ui.control.Slider
        FrequencySliderLabel     matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        Lamp                     matlab.ui.control.Lamp
        Scope_off                matlab.ui.control.Button
        Scope_on                 matlab.ui.control.Button
        XZoomSlider              matlab.ui.control.Slider
        XZoomSliderLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        u % the udp object
        remote_ip % the remote ip address
        frequency % the current desierd shift freq
        PD_line % Description
        Error_line % Description
        Trig_line % Description
        Graph_lims % Description
        update_time % Description
        last_time % Description
    end

    
    methods (Access = private)


        function new_udp_data(app, ~, ~)
                header = read(app.u,1,"uint8");
                fprintf('header is: %d\n', header);
                if (header =='%')
                    data_length = read(app.u,2,"uint8");
                    N = typecast(uint8(data_length), 'uint16')-2;
                    data_trig = read(app.u,2,"uint8");
                    trig_index = typecast(uint8(data_trig), 'uint16');
                    %fprintf('trig_index= %d\n',trig_index);
                    %fprintf('reading N= %d\n',N);
                    data = read(app.u,N,"uint8");
                    Photodiode = data(1:2:end)*3.3/2^8;
                    Error = data(2:2:end)*-3.3/2^8+app.ErrorOffset.Value;
                    %make x/time array
                    x_vals = double(1:length(Error))-double(trig_index);
                    %%plot this
                    set(app.PD_line,'YData',Photodiode,'XData',x_vals);
                    set(app.Error_line,'YData',Error,'XData',x_vals);
                    set(app.Trig_line,'Value',0);
                    app.UIAxes.XLim = app.Graph_lims;
                    
                    %%apply zooms to PD axis
                    yyaxis(app.UIAxes,"left");
                    app.UIAxes.YLim = [(app.PD_Y.Value-app.PD_Zoom.Value),(app.PD_Y.Value+app.PD_Zoom.Value)];
                elseif (header =='&')
                    %adc value
                    data_adc = read(app.u,2,"uint8");
                    app.frequency = double(typecast(uint8(data_adc), 'uint16'))-2^11;
                    app.FrequencyEditField.Value = app.frequency;
                    app.FrequencySlider.Value = app.frequency;
                elseif (header =='!')
                    %ping respinse
                    ip1 = read(app.u,1,"uint8");
                    ip2 = read(app.u,1,"uint8");
                    ip3 = read(app.u,1,"uint8");
                    ip4 = read(app.u,1,"uint8");
                    %show this in terminal
                    fprintf('Ping return from IP: %d.%d.%d.%d\n',ip1,ip2,ip3,ip4);
                    %display on app
                    app.ListBox.Items(end+1) = {sprintf('%d.%d.%d.%d',ip1,ip2,ip3,ip4)};
                else
                    flush(app.u);
                    return
                end
                
                flush(app.u);
                app.last_time = datetime("now");
                app.Lamp.Color = [0,1,0];

        end
        
        function update_ADC(app)
            %contrain number
            app.frequency = round(app.frequency); %make sure it is an integier
            app.frequency = min(2^11,app.frequency);
            app.frequency = max(-2^11,app.frequency); %limit to signed 2^12 number
            unsigned_int = app.frequency+2^11;
            %send new value to arduino
            write(app.u,[char(35),int2str(unsigned_int),char(0)],"char",app.remote_ip,9999);
            
            %from here, update slide and text box
            app.FrequencyEditField.Value = app.frequency;
            app.FrequencySlider.Value = app.frequency;
        end
        
        
        function new_data_timer(app, ~, ~)
            %check if there is new data
            delay = seconds(datetime("now")-app.last_time);
            if delay>2
                app.Lamp.Color = [1,0,0];
            else
                app.Lamp.Color = [0,1,0];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %setup UDP
            app.u = udpport("byte","IPV4","LocalPort",9001,"Timeout",1000,"ByteOrder","little-endian"); %little-endian big-endian
            configureCallback(app.u,"byte",1,@app.new_udp_data);
            
            app.remote_ip = app.IPEditField.Value;

            %setup timers
            % Delete all timers from memory.
            listOfTimers = timerfindall;
            if ~isempty(listOfTimers)
                delete(listOfTimers(:));
            end
            app.last_time = datetime("now");
            app.update_time = timer("Period",1,"ExecutionMode","fixedRate","TasksToExecute",Inf);
            app.update_time.TimerFcn = @(~,~) app.new_data_timer;
            start(app.update_time);
            
            %setup plot
            app.PD_line = plot(app.UIAxes,[-100:100],ones(201,1));
            hold(app.UIAxes,'on');
            yyaxis(app.UIAxes,"right");
            app.UIAxes.YLim = [-0.5 0.5];
            app.Error_line = plot(app.UIAxes,[-100:100],zeros(201,1));
            app.Trig_line = xline(app.UIAxes,0);
            yline(app.UIAxes,0);
            grid(app.UIAxes,"on");
            hold(app.UIAxes,'off');
            app.Graph_lims = [-100, 100];
            app.XZoomSlider.Value = app.Graph_lims(2);
            app.Lamp.Color = [0,1,0];
            
            %setup remote ADC
            app.frequency = 0;
            update_ADC(app);
            
        end

        % Button pushed function: Freq_zero
        function Freq_zeroButtonPushed(app, event)
            app.frequency = 0;
            update_ADC(app);
        end

        % Button pushed function: Freq_up1
        function Freq_up1ButtonPushed(app, event)
            app.frequency = app.frequency+1;
            update_ADC(app);
        end

        % Button pushed function: Slow_Lock
        function Slow_LockButtonPushed(app, event)
            write(app.u,[char(35),'enable_slow',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: Slow_Unlock
        function Slow_UnlockButtonPushed(app, event)
            write(app.u,[char(35),'disable_slow',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: Fast_Lock
        function Fast_LockButtonPushed(app, event)
            write(app.u,[char(35),'enable_fast',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: Fas_Unlock
        function Fas_UnlockButtonPushed(app, event)
            write(app.u,[char(35),'disable_fast',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: Freq_up10
        function Freq_up10ButtonPushed(app, event)
            app.frequency = app.frequency+10;
            update_ADC(app);
        end

        % Button pushed function: Freq_down1
        function Freq_down1ButtonPushed(app, event)
            app.frequency = app.frequency-1;
            update_ADC(app);
        end

        % Button pushed function: Freq_down10
        function Freq_down10ButtonPushed(app, event)
            app.frequency = app.frequency-10;
            update_ADC(app);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            app.frequency = value;
            update_ADC(app);
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
            app.frequency = value;
            update_ADC(app);
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = round(event.Value);
            %write(app.u,[char(35),int2str(changingValue+2^11),char(0)],"char",app.remote_ip,9999);
            %dont update as you go. Makes it crash
            app.FrequencyEditField.Value = changingValue;
        end

        % Button pushed function: Freq_up50
        function Freq_up50ButtonPushed(app, event)
            app.frequency = app.frequency+50;
            update_ADC(app);
        end

        % Button pushed function: Freq_down50
        function Freq_down50ButtonPushed(app, event)
            app.frequency = app.frequency-50;
            update_ADC(app);
        end

        % Value changed function: IPEditField
        function IPEditFieldValueChanged(app, event)
            value = app.IPEditField.Value;
            app.remote_ip = value;
        end

        % Value changing function: XZoomSlider
        function XZoomSliderValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.XLim = [-changingValue, changingValue];
            app.Graph_lims = [-changingValue, changingValue];
        end

        % Button pushed function: Scope_off
        function Scope_offButtonPushed(app, event)
            write(app.u,[char(35),'scope_off',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: Scope_on
        function Scope_onButtonPushed(app, event)
            write(app.u,[char(35),'scope_on',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ListBox.Items="";
        end

        % Button pushed function: GetADCValueButton
        function GetADCValueButtonPushed(app, event)
            write(app.u,[char(35),'ADC_value',char(0)],"char",app.remote_ip,9999);
        end

        % Button pushed function: PingButton
        function PingButtonPushed(app, event)
            write(app.u,[char(35),'ping',char(0)],"char",'192.168.1.255',9999); %send to broadcast address
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.IPEditField.Value = value;
            app.remote_ip = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FrequencySliderLabel = uilabel(app.LeftPanel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.LeftPanel);
            app.FrequencySlider.Limits = [-2047 2047];
            app.FrequencySlider.MajorTicks = [-2047 -1536 -1024 -512 0 512 1024 1536 2047];
            app.FrequencySlider.MajorTickLabels = {'-2047', '-1536', '-1024', '-512', '0', '512', '1024', '1536', '2047'};
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.Freq_zero = uibutton(app.LeftPanel, 'push');
            app.Freq_zero.ButtonPushedFcn = createCallbackFcn(app, @Freq_zeroButtonPushed, true);
            app.Freq_zero.Text = '0';
            app.Freq_up1 = uibutton(app.LeftPanel, 'push');
            app.Freq_up1.ButtonPushedFcn = createCallbackFcn(app, @Freq_up1ButtonPushed, true);
            app.Freq_up1.Text = '^';
            app.Freq_up10 = uibutton(app.LeftPanel, 'push');
            app.Freq_up10.ButtonPushedFcn = createCallbackFcn(app, @Freq_up10ButtonPushed, true);
            app.Freq_up10.Text = '^^';
            app.Freq_down1 = uibutton(app.LeftPanel, 'push');
            app.Freq_down1.ButtonPushedFcn = createCallbackFcn(app, @Freq_down1ButtonPushed, true);
            app.Freq_down1.Text = 'v';
            app.Freq_down10 = uibutton(app.LeftPanel, 'push');
            app.Freq_down10.ButtonPushedFcn = createCallbackFcn(app, @Freq_down10ButtonPushed, true);
            app.Freq_down10.Text = 'vv';
            app.Slow_Lock = uibutton(app.LeftPanel, 'push');
            app.Slow_Lock.ButtonPushedFcn = createCallbackFcn(app, @Slow_LockButtonPushed, true);
            app.Slow_Lock.Text = 'Lock';
            app.Slow_Unlock = uibutton(app.LeftPanel, 'push');
            app.Slow_Unlock.ButtonPushedFcn = createCallbackFcn(app, @Slow_UnlockButtonPushed, true);
            app.Slow_Unlock.Text = 'Unlock';
            app.SLOWLabel = uilabel(app.LeftPanel);
            app.SLOWLabel.Text = 'SLOW';
            app.FASTLabel = uilabel(app.LeftPanel);
            app.FASTLabel.Text = 'FAST';
            app.Fast_Lock = uibutton(app.LeftPanel, 'push');
            app.Fast_Lock.ButtonPushedFcn = createCallbackFcn(app, @Fast_LockButtonPushed, true);
            app.Fast_Lock.Text = 'Lock';
            app.Fas_Unlock = uibutton(app.LeftPanel, 'push');
            app.Fas_Unlock.ButtonPushedFcn = createCallbackFcn(app, @Fas_UnlockButtonPushed, true);
            app.Fas_Unlock.Text = 'Unlock';
            app.FrequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.Freq_up50 = uibutton(app.LeftPanel, 'push');
            app.Freq_up50.ButtonPushedFcn = createCallbackFcn(app, @Freq_up50ButtonPushed, true);
            app.Freq_up50.Text = '^^^';
            app.Freq_down50 = uibutton(app.LeftPanel, 'push');
            app.Freq_down50.ButtonPushedFcn = createCallbackFcn(app, @Freq_down50ButtonPushed, true);
            app.Freq_down50.Text = 'vvv';
            app.IPEditFieldLabel = uilabel(app.LeftPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.LeftPanel, 'text');
            app.IPEditField.ValueChangedFcn = createCallbackFcn(app, @IPEditFieldValueChanged, true);
            app.IPEditField.Value = '192.168.1.1';
            app.ErrorOffsetSpinnerLabel = uilabel(app.LeftPanel);
            app.ErrorOffsetSpinnerLabel.Text = 'Error Offset';
            app.ErrorOffset = uispinner(app.LeftPanel);
            app.ErrorOffset.Step = 0.01;
            app.PDYLabel = uilabel(app.LeftPanel);
            app.PDYLabel.Text = 'PD Y';
            app.PD_Y = uispinner(app.LeftPanel);
            app.PD_Y.Step = 0.1;
            app.PD_Y.Limits = [0 3.3];
            app.PD_Y.Value = 1;
            app.PDZoomLabel = uilabel(app.LeftPanel);
            app.PDZoomLabel.Text = 'PD Zoom';
            app.PD_Zoom = uispinner(app.LeftPanel);
            app.PD_Zoom.Step = 0.1;
            app.PD_Zoom.Limits = [0.1 2];
            app.PD_Zoom.Value = 1;
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.GetADCValueButton = uibutton(app.LeftPanel, 'push');
            app.GetADCValueButton.ButtonPushedFcn = createCallbackFcn(app, @GetADCValueButtonPushed, true);
            app.GetADCValueButton.Text = 'Get ADC Value';
            app.ListBox = uilistbox(app.LeftPanel);
            app.ListBox.Items = {'192.168.1.50', '192.168.1.51', '192.168.1.52'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.PingButton = uibutton(app.LeftPanel, 'push');
            app.PingButton.ButtonPushedFcn = createCallbackFcn(app, @PingButtonPushed, true);
            app.PingButton.Text = 'Ping';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Scope')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.XZoomSliderLabel = uilabel(app.RightPanel);
            app.XZoomSliderLabel.Text = 'X-Zoom';
            app.XZoomSlider = uislider(app.RightPanel);
            app.XZoomSlider.Limits = [1 500];
            app.XZoomSlider.MajorTicks = [1 100 200 300 400 500];
            app.XZoomSlider.ValueChangingFcn = createCallbackFcn(app, @XZoomSliderValueChanging, true);
            app.XZoomSlider.Value = 10;
            app.Scope_on = uibutton(app.RightPanel, 'push');
            app.Scope_on.ButtonPushedFcn = createCallbackFcn(app, @Scope_onButtonPushed, true);
            app.Scope_on.Text = 'On';
            app.Scope_off = uibutton(app.RightPanel, 'push');
            app.Scope_off.ButtonPushedFcn = createCallbackFcn(app, @Scope_offButtonPushed, true);
            app.Scope_off.Text = 'Off';
            app.Lamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Remote_lock

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end